classdef SmithChartIntermediate < matlab.apps.AppBase
    %SMITHCHARTINTERMEDIATE Interactive Smith Chart
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2025
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Portions of this code were generated with assistance from ChatGPT
    %   (OpenAI, 2025).
    %   
    %   Image: SmithChart1.png
    %   Source: adapted from MTT-S, "Smithchart with logo", 2023. 
    %   Retrieved from: https://mtt.org/smith-chart/
    %   
    %   Image: SmithChart2Edit2.png
    %   Source: adapted from MTT-S, "Smithchart with logo", 2023. 
    %   Retrieved from: https://mtt.org/smith-chart/
    %   
    %   Interactive Smith Chart that allows user selected points and
    %   adjustable values. Includes an "automatic ruler" to read basic
    %   Smith Chart values and auto-updating entry boxes. Imaginary input
    %   and 'inf' is allowed. 
    %   
    %   Uses the Okabe–Ito palette (color blind friendly).

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LengthEditField             matlab.ui.control.NumericEditField
        LengthEditFieldLabel        matlab.ui.control.Label
        ZinEditField                matlab.ui.control.NumericEditField
        ZinEditFieldLabel           matlab.ui.control.Label
        ShowstartendpointsCheckBox  matlab.ui.control.CheckBox
        ZCirclesCheckBox            matlab.ui.control.CheckBox
        ResetRotationButton         matlab.ui.control.Button
        RotateToggleButton          matlab.ui.control.StateButton
        SWRCircleCheckBox           matlab.ui.control.CheckBox
        YL50Label                   matlab.ui.control.Label
        ZL50Label                   matlab.ui.control.Label
        ZLLabel_2                   matlab.ui.control.Label
        MainPanel                   matlab.ui.container.Panel
        RFLCOEFFEorILabel           matlab.ui.control.Label
        TRANSMCOEFFEorILabel        matlab.ui.control.Label
        TRANSMCOEFFPLabel           matlab.ui.control.Label
        SWPEAKCONSTPLabel           matlab.ui.control.Label
        RFLLOSSdBLabel              matlab.ui.control.Label
        SWLOSSCOEFFLabel            matlab.ui.control.Label
        ATTENdbLabel                matlab.ui.control.Label
        RFLCOEFFPLabel              matlab.ui.control.Label
        RTNLOSSdBLabel              matlab.ui.control.Label
        dBSLabel                    matlab.ui.control.Label
        SWRLabel                    matlab.ui.control.Label
        RulerAxes                   matlab.ui.control.UIAxes
        SmithAxes                   matlab.ui.control.UIAxes
        XLjLabel                    matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        RLLabel                     matlab.ui.control.Label
        yLEditField                 matlab.ui.control.EditField
        yLEditFieldLabel            matlab.ui.control.Label
        gammaAngleEditField         matlab.ui.control.NumericEditField
        degEditFieldLabel           matlab.ui.control.Label
        YLEditField                 matlab.ui.control.EditField
        YLLabel                     matlab.ui.control.Label
        zLEditField                 matlab.ui.control.EditField
        zLEditFieldLabel            matlab.ui.control.Label
        ZLEditField                 matlab.ui.control.EditField
        ZLLabel                     matlab.ui.control.Label
        gammaMagEditField           matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        RulerCheckBox               matlab.ui.control.CheckBox
        CircuitAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % TODO improvement idea (add to Basic): need a way to harmonize
        % automatic or toggled "pan" mode and the user actually selecting
        % points. use the toggle on or off thing and a "select"
        % mode/condition

        % TODO select vs. pan implementation: search tic() and toc(). When
        % release: call toc. check performance. 

        % TODO change the name of the image to something better

        % TODO change direction to TWG or TWL... (app.rotateDirection)
        % TODO after reset rotation, slight visual "bug" (breifly shows
        % last lambda circle)

        % Below values are for SmithChart1.png TODO update names!
        r1 = 1625; % Length (in pixels) of inner radius of Smith Chart
        r2 = 1875; % Length (in pixels) of the outer radius of the Smith Chart
        r3 = 1460; % Length (in pixels) of gamma = 1 for the Ruler
        r4 = 2000; % Length (in pixels) of the radius of the wavelength indicator

        Z0 = 50; % (ohms) Assumed characteristic impedance of transmission line

        ZL; % (ohms) Load impedance
        zL; % Normalized load impedance
        YL; % (mhos) Load admittance
        yL; % Normalized load admittance

        gamma; % Reflection coefficient 
        gammaMag = 0; % Magntude of reflection coefficient
        gammaAngle = 0; % (deg) Angle of reflection coefficient
        
        zLPoint; % Scatter handle for the current point on the Smith Chart
        zinPoint; % Scatter handle for the current Zin point on the Smith Chart
        imgIntersection; % Plot handle for the intersecting imaginary line
        realIntersection; % Plot handle for the intersecting real line
        swrCircle; % Plot handle for the SWR circle

        priorTheta; % TODO

        innerRuler; % Plot from the center to 'point' on the chart 
        outerRuler; % Plot from the 'point' to the outer radius on the chart

        lambdaCircle; % Plot from initial to ending point for rotating and measuring wavelength distance
        initialLine; % Plot to mark the initial line for rotation
        startingTheta; % Theta value for the start of measurement
        finalLine; % Plot to mark the final line for rotation
        rotatingNow = false; % Checks if the user has begun rotating or not
        rotateDirectionTxt; % Textbox that indicates the direction of rotation
        rotateDirection = "CW"; % "CC" is counterclockwise and "CW" is clockwise. 
        wavelengthIndicator; % Indicator to note the total wavelength travelled while measuring.
        wavelengthStartIndicator; % Indicator for the starting wavelength value on the chart
        wavelengthEndIndicator; % Indicator for the ending wavelength value on the chart
        rotateRing; % Patch handle for the particular ring in question for rotating
        circleQuality = 500; % Number of points in 1 full rotation of lambdaCircle

        % Circuit Diagram:
        ZLTextCircuit; % ZL indicator on the circuit diagram
        ZinTextCircuit; % Zin indicator on the circuit diagram
        wavelengthTextCircuit; % wavelength indicator on the circuit diagram
        wavelengthIndicatorCircuit; % Plot object to show distance from Zin or ZL
        zinCircuitPt1; % Scatter object for the top zin marker
        zinCircuitPt2; % Scatter object for the bottom zin marker

        mouseListener; % Listener object for selecting the Smith Chart

        % Array of plot handles for making the Smith Chart ruler markings. The index
        % of the array corresponds to the # ruler going from left to right
        % and top to bottom (ej - index 1 is the SWR ruler and index 3 is
        % RTN. LOSS [dB]). rulerMarkings will refer to the green line,
        % rulerPoints will refer to the point at the end of each marking.
        rulerMarkings = gobjects(1,7); 
        rulerPoints = gobjects(1,7);

        % Rotating dynamics
        rotateToggleOn = false; % Boolean to determine if the user is in the rotating state.
        mouseHeld; % Boolean to track if the mouse button is held

        % Okabe–Ito color blind friendly palette:
        okabeBlue = [0, 114, 178]/255;
        okabeReddishPurple = [204, 121, 167]/255;
        okabeBluishGreen = [0.0000, 0.6196, 0.4510];
        okabeSkyBlue = [0.3373, 0.7059, 0.9137];
        okabeOrange = [0.9020 0.6235 0];
        okabeYellow = [240, 228, 66]/255;
        okabeBlack = [0, 0, 0];                
        okabeVermilion = [213, 94, 0]/255;
        okabeLightVermilion = [0.835, 0.368, 0, 0.3];
        
        % Additional listener:
        mouseReleaseListener
    end

    methods (Access = private)

        % Updates the location of the point on the Smith Chart according
        % to current Smith Chart values. Updates the ruler markings and
        % Z circles as well
        function updateSmithPoint(app)
            % Update the z circles:
            updateZCircles(app);

            % Update SWR Circle:
            updateSWRCircle(app);

            % Update the ruler and ruler values:
            updateRuler(app);
            updateRulerMarkings(app);
            updateRulerValues(app);

            % Update app.zLPoint location:
            r = abs(app.gamma);
            theta = angle(app.gamma);
            x = app.r1*r*cos(theta);
            y = app.r1*r*sin(theta);

            app.zLPoint.XData = x;
            app.zLPoint.YData = y;
        end

        function updateZCircles(app)
            %UPDATEZCIRCLES Traces lines for the current point
            %   MYFUNCTION(APP) Traces lines for the current point for this
            %   app.
            %   This function will trace the real and imaginary lines for
            %   the current point plotted on the Smith Chart. It will do so
            %   by spanning over the real and imaginary components of ZL
            %   independently of one another and plotting.

            % Author: Carsen Villegas (with assistance from ChatGPT)
            % Date: 2025-06-04

            % Extract the components of the current ZL
            realComponent = real(app.ZL);
            imgComponent = imag(app.ZL);

            % The real line, or circle, will be traced by maintaining the
            % real component of ZL and plotting over the full range of
            % imaginary values. A higher density of points centered around
            % 0 is needed to make the plot look like a smooth circle.
            N = 1000; % Number of points
            a = logspace(0, 4, N/2); % Higher density of points around 0
            A = [-fliplr(a), 0, a];  % full range from -1e4 to 1e4, clustered near 0
            realCircleSpan = realComponent.*ones(1, 1001) + A.*1i; % All ZL values to trace the line

            % Now using gamma to extract the exact x and y coordinates to
            % plot for all ZL values above
            gm = (realCircleSpan - app.Z0)./(realCircleSpan + app.Z0); % gamma
            r = abs(gm);
            theta = angle(gm);
            x = app.r1.*r.*cos(theta);
            y = app.r1.*r.*sin(theta);

            realX = [x, x(1)]; % Appending the first data points to give the appearance of a full circle
            realY = [y, y(1)];

            % Update the real intersection plot:
            app.realIntersection.XData = realX;
            app.realIntersection.YData = realY;

            % For the imaginary line will be traced by maintaining the
            % imaginary component of ZL and plotting over the full range of
            % real values. A higher density of points centered around 0 is
            % needed to make the plot look smooth. 0 is appended to the
            % real component to start at the very edge of the Smith Chart.
            imgCircleSpan = [0, logspace(0, 5, 1000)] + imgComponent.*ones(1,1001).*1i;

            gm = (imgCircleSpan - app.Z0)./(imgCircleSpan + app.Z0); % gamma
            r = abs(gm);
            theta = angle(gm);
            x = app.r1.*r.*cos(theta);
            y = app.r1.*r.*sin(theta);

            imgX = x;
            imgY = y;

            % Update the imaginary intersection plot:
            app.imgIntersection.XData = imgX;
            app.imgIntersection.YData = imgY;

        end


        % Imports and adjusts images to align with axes objects
        function loadImages(app)
            % Load Smith Chart image
            img = imread('SmithChart1.png'); % Chart itself

            % Image dimensions:
            squareSize = 3820;

            halfSize = floor(squareSize / 2);
            [imgHeight, imgWidth, ~] = size(img);

            % (0,0) Coordinates in pixels:
            centerX = 1910;
            centerY = 1910;

            % Display on app.SmithAxes using image() 
            h = image(app.SmithAxes, img);  % Display the image
            set(h, 'Interpolation', 'bilinear');
            axis(app.SmithAxes, 'off');  % Hide axis ticks

            % Hold for overlay plots
            hold(app.SmithAxes, 'on');

            % Turn off title:
            app.SmithAxes.Title.Visible = 'off';

            % Align with plot:
            % Ensure square aspect ratio:
            pbaspect(app.SmithAxes, [1 1 1])
            axis(app.SmithAxes, 'manual');

            % Set axis limits to match the image dimensions, with (0, 0) centered
            % Ensure the center of the image corresponds to (0, 0) on the
            % axes 
            % TODO readjust auto-resize to fit
            % these changes. 
            z = 200; % Additional padding to allow for rotation circle. 
            set(app.SmithAxes, 'XLim', [-halfSize-z halfSize+z], 'YLim', [-halfSize-z halfSize+z]);

            % Adjust the image position to place its center at (0, 0) in the axes
            set(h, 'XData', [-halfSize halfSize] + centerX - imgWidth/2, ...
                'YData', [halfSize -halfSize] + centerY - imgHeight/2);

            set(app.SmithAxes, 'YDir', 'normal');

            % Load the ruler portion of the Smith Chart:
            img = imread('SmithChart2Edit2.png');

            % h = image(app.RulerAxes, img_small);
            h = image(app.RulerAxes, img);

            set(h, 'Interpolation', 'bilinear');
            axis(app.RulerAxes, 'off');
            hold(app.RulerAxes, 'on');
            axis(app.RulerAxes, 'image')

            app.RulerAxes.OuterPosition = app.RulerAxes.Position;
            app.RulerAxes.Title.Visible = 'off';

            % Load Zin/ZL Circuit diagram:
            img = imread('ZinZLCircuitDiagram.png');

            h = image(app.CircuitAxes, img);

            set(h, 'Interpolation', 'bilinear');
            axis(app.CircuitAxes, 'off');
            hold(app.CircuitAxes, 'on');
            axis(app.CircuitAxes, 'image')
            app.CircuitAxes.Title.Visible = 'off';

        end
       
        function updateSmithValues(app, userUpdate)
            %updateSmithValues Updates all other relevant values for the
            %Smith Chart TODO includes changes to circuit diagram
            %   The user specifies which value was manually updated in the
            %   application. All other values are updated accordingly to
            %   avoid dependency errors. Also updates textboxes
            %   accordingly.
            %
            %   INPUTS:
            %     userUpdate - The value the user manually updated

            % gammaMag, gammaAngle, gamma, ZL, zL, YL, yL,

            % Author: Carsen Villegas (with assistance from ChatGPT)
            % Date: 2025-06-16

            switch userUpdate
                case 'gammaMag'
                    app.gamma = app.gammaMag*exp(1i*deg2rad(app.gammaAngle));

                    app.ZL = app.Z0*(1+app.gamma)/(1-app.gamma);
                    app.zL = app.ZL/app.Z0;

                    % Adjustment: Check Γ = arg(-1) case (irregular output)
                    if (mod(app.gammaAngle, 180) == 0) && (app.gammaMag == 1)
                        app.YL = inf;
                        app.yL = inf;
                    else
                        app.YL = 1/app.ZL;
                        app.yL = 1/app.zL;
                    end

                    % Update fields:
                    app.ZLEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.zLEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.YLEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.yLEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 
                case 'gammaAngle'
                    app.gamma = app.gammaMag*exp(1i*deg2rad(app.gammaAngle));

                    app.ZL = app.Z0*(1+app.gamma)/(1-app.gamma);
                    app.zL = app.ZL/app.Z0;

                    % Adjustment: Check Γ = arg(-1) case (irregular output)
                    if (mod(app.gammaAngle, 180) == 0) && (app.gammaMag == 1)
                        app.YL = inf;
                        app.yL = inf;
                    else
                        app.YL = 1/app.ZL;
                        app.yL = 1/app.zL;
                    end

                    app.ZLEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.zLEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.YLEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.yLEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    % TODO doing now: create a new updateSmithValues for
                    % this app. updates circuit diagram output as well. 
                case 'zL'
                    app.ZL = app.Z0*app.zL;
                    app.YL = 1/app.ZL;
                    app.yL = 1/app.zL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.ZLEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.YLEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.yLEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));

                case 'yL'
                    app.zL = 1/app.yL;
                    app.ZL = app.Z0*app.zL;
                    app.YL = 1/app.ZL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.ZLEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.YLEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.zLEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));

                case 'ZL'
                    app.zL = app.ZL/app.Z0;
                    app.YL = 1/app.ZL;
                    app.yL = 1/app.zL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.zLEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.YLEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.yLEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));

                case 'YL'
                    app.ZL = 1/app.YL;
                    app.zL = app.ZL/app.Z0;
                    app.yL = 1/app.zL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.ZLEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.zLEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.yLEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));
            end
        end

        
        % Checks to see if coordinates (x,y) are in bounds of the Smith
        % Chart
        function result = isInBounds(app, x, y)
            result = sqrt(x^2 + y^2) <= app.r1;
        end

        % Checks to see if coordinates (x,y) are sufficiently close to the
        % Smith Chart for rotating. This allows the user to safely select
        % text boxes on the right without moving the point while in rotate
        % mode. 
        function result = isCloseEnough(app, x, y)
            buffer = 1500;
            result = sqrt(x^2 + y^2) <= app.r2 + buffer; % Outer radius + buffer is deemed "close enough" to the chart.
        end

        % Updates the automatic ruler from the center point through 'app.zLPoint'
        function updateRuler(app)
            r = abs(app.gamma);
            theta = angle(app.gamma);

            % 'point' location: (pixel location)
            x = app.r1*r*cos(theta); 
            y = app.r1*r*sin(theta);

            % Update the ruler:
            set(app.innerRuler, 'XData', [0 x], 'YData', [0 y]);
            set(app.outerRuler, 'XData', [x app.r2*cos(theta)], 'YData', [y app.r2*sin(theta)]);
            
        end
        
        % Highlights the ring on the Smith Chart for the reflection coefficient
        function fillGammaAngle(app)
            theta = linspace(0, 2*pi, 500);  % Angle values
            ra = 3597 - 1910;  % Inner radius
            rb = 3659 - 1910;  % Outer radius

            % Outer circle (counterclockwise)
            x_outer = rb * cos(theta);
            y_outer = rb * sin(theta);

            % Inner circle (clockwise to avoid overlap issue)
            x_inner = fliplr(ra * cos(theta));
            y_inner = fliplr(ra * sin(theta));

            % Combine coordinates
            x_ring = [x_outer, x_inner];
            y_ring = [y_outer, y_inner];

            % Plot filled annulus
            h = fill(app.SmithAxes, x_ring, y_ring, app.okabeSkyBlue); 

            % Set transparency
            h.FaceAlpha = 0.3;

            % Remove the outline:
            h.EdgeColor = 'none';

            % Remove pickable parts:
            h.PickableParts = 'none';
        end

        % Highlights the ring on the Smith Chart for the rotation direction
        % according to app.rotateDirection
        function fillRotateDirection(app)
            theta = linspace(0, 2*pi, 500);  % Angle values
            
            % Establish radii according to rotate direction:
            if app.rotateDirection == "TWL"
                ra = 3659 - 1910;  % Inner radius
                rb = 3722 - 1910;  % Outer radius
            elseif app.rotateDirection == "TWG"
                ra = 3722 - 1910;  % Inner radius
                rb = 3783 - 1910;  % Outer radius
            end

            % Outer circle (counterclockwise)
            x_outer = rb * cos(theta);
            y_outer = rb * sin(theta);

            % Inner circle (clockwise to avoid overlap issue)
            x_inner = fliplr(ra * cos(theta));
            y_inner = fliplr(ra * sin(theta));

            % Combine coordinates
            x_ring = [x_outer, x_inner];
            y_ring = [y_outer, y_inner];

            % Plot filled annulus
            set(app.rotateRing, 'XData', x_ring, 'YData', y_ring)
        end
        
        % Updates the positioning of components within MainPanel. A custom
        % psuedo-grid layout is used to allow the Smith Chart and ruler to
        % be aligned. The arrangement order is as follows: The Smith Chart
        % is given a weight of 5 out of 6 parts vertically. The Smith
        % Chart, embedded in "SmithAxes", has a fixed, square aspect ratio,
        % so this vertical weight will also expand the chart horizontally.
        % Next, the ruler's height (also with fixed original aspect ratio)
        % is adjusted so that it is properly aligned with the Smith Chart.
        % For simplicity and to prevent misalignement between the two
        % images, their widths are artifically expanded beyond the width of
        % the MainPanel. This prevents their images from shrinking based on
        % width. Alignment is, then, based on height. Finally, the ruler
        % markings text boxes are aligned with the MainPanel. 
        function onResize(app)
            % Get the available space from the parent container
            panelPos = app.MainPanel.Position;  % [x, y, width, height]
            panelW = panelPos(3);
            panelH = panelPos(4);

            % Total vertical weight = 5 + 1 = 6
            totalWeight = 6;

            % Row heights (based on weights)
            heightTop = (5 / totalWeight) * panelH;
            heightBottom = (1 / totalWeight) * panelH;

            % Optional margin (optional)
            spacing = 5;  % pixels between rows

            % Reduce for spacing
            heightTop = heightTop - spacing / 2;
            heightBottom = heightBottom - spacing / 2;

            % Horizontal position (full width)
            x = 0;
            w = panelW;

            % Vertical positions (bottom-up)
            yBottom = 0;
            yTop = heightBottom + spacing;

            % Set positions of the axes
            app.RulerAxes.Units = 'pixels';
            app.SmithAxes.Units = 'pixels';

            % Values were obtained through linear regression and
            % correlating the ruler's height and the SmithAxes' height for
            % varying window sizes for aligned scenarios. A debugging tool
            % was used to manually alter the ruler's height to find aligned
            % scenarios. 
            rH = (heightTop + 101.2)/5.641; % TODO

            % Note: yTop has a 10 pixel subtraction to prevent the top of
            % the panel from covering up the Smith Chart. See the function
            % note for the reason of the width expansion.
            app.RulerAxes.Position = [x - 1000, yBottom, w + 2000, rH];
            app.SmithAxes.Position = [x - 1000, yTop - 30, w + 2000, heightTop];

            % Ensure the ruler labels are aligned properly. All labels will
            % have 22 height and width 180 (to avoid clipping values). 

            % Align the left values with the left side of the panel and
            % above the ruler:
            rulerTop = yBottom + rH; % y value of the top of the ruler
            labelMargin = 1; % Margin between labels
            labelWidth = 180;
            labelHeight = 22;
            app.SWRLabel.Position = [5, rulerTop + 4*labelHeight + labelMargin, labelWidth, labelHeight];
            app.dBSLabel.Position = [5, rulerTop + 3*labelHeight + labelMargin, labelWidth, labelHeight];
            app.RTNLOSSdBLabel.Position = [5, rulerTop + 2*labelHeight + labelMargin, labelWidth, labelHeight];
            app.RFLCOEFFPLabel.Position = [5, rulerTop + labelHeight + labelMargin, labelWidth, labelHeight];
            app.RFLCOEFFEorILabel.Position = [5, rulerTop, labelWidth, labelHeight];

            % Align the right values with the right side of the panel and
            % above the ruler:
            xLoc = panelW - labelWidth;
            app.ATTENdbLabel.Position = [xLoc - 5, rulerTop + 5*labelHeight + labelMargin, labelWidth, labelHeight];
            app.SWLOSSCOEFFLabel.Position = [xLoc - 5, rulerTop + 4*labelHeight + labelMargin, labelWidth, labelHeight];
            app.RFLLOSSdBLabel.Position = [xLoc - 5, rulerTop + 3*labelHeight + labelMargin, labelWidth, labelHeight];
            app.SWPEAKCONSTPLabel.Position = [xLoc - 5, rulerTop + 2*labelHeight + labelMargin, labelWidth, labelHeight];
            app.TRANSMCOEFFPLabel.Position = [xLoc - 5, rulerTop + labelHeight + labelMargin, labelWidth, labelHeight];
            app.TRANSMCOEFFEorILabel.Position = [xLoc - 5, rulerTop, labelWidth, labelHeight];

        end


        function updateRulerMarkings(app)
            % Updates the markings on the Smith Chart Ruler:

            % Calculate the corresponding pixel measurement:
            r = abs(app.gamma);
            innerRulerLength = r*app.r3;

            app.rulerMarkings(1).YData = [222, 222];
            app.rulerMarkings(2).YData = [222, 222];

            app.rulerMarkings(3).YData = [335, 335];
            app.rulerMarkings(4).YData = [335, 335];

            app.rulerMarkings(5).YData = [447, 447];
            app.rulerMarkings(6).YData = [447, 447];

            app.rulerMarkings(7).YData = [559, 559];

            % For the first 6 measurements:
            for i = 1:6
                if mod(i, 2) == 0 % Right side of Center
                    app.rulerMarkings(i).XData = [1910 - innerRulerLength, 1910];
                else % Left side of center
                    app.rulerMarkings(i).XData = [1910 + innerRulerLength, 1910];
                end
            end

            app.rulerMarkings(7).XData = [450, 450 + app.r3 + innerRulerLength];

            % Set the rulerPoints:
            set(app.rulerPoints(1), 'XData', 1910 - innerRulerLength, 'YData', 222);
            set(app.rulerPoints(2), 'XData', 1910 + innerRulerLength, 'YData', 222);
            set(app.rulerPoints(3), 'XData', 1910 - innerRulerLength, 'YData', 335);
            set(app.rulerPoints(4), 'XData', 1910 + innerRulerLength, 'YData', 335);
            set(app.rulerPoints(5), 'XData', 1910 - innerRulerLength, 'YData', 447);
            set(app.rulerPoints(6), 'XData', 1910 + innerRulerLength, 'YData', 447);
            set(app.rulerPoints(7), 'XData', 450 + app.r3 + innerRulerLength, 'YData', 559);

        end
        
        % Updates the textboses reporting values read off from the Smith
        % Chart ruler
        function updateRulerValues(app)
            SWR = (1 + app.gammaMag)/(1 - app.gammaMag);
            app.SWRLabel.Text = sprintf("SWR = %.2f", SWR);

            dBS = 20*log10(SWR);
            app.dBSLabel.Text = sprintf("dBS = %.2f", dBS);

            rtnLoss = -20*log10(app.gammaMag);
            app.RTNLOSSdBLabel.Text = sprintf("RTN. LOSS [dB] = %.2f", rtnLoss);

            rflCoeffP = app.gammaMag^2;
            app.RFLCOEFFPLabel.Text = sprintf("RFL. COEFF, P = %.2f", rflCoeffP);

            attenDB = -10*log10(app.gammaMag);
            app.ATTENdbLabel.Text = sprintf("ATTEN. [db] = %.2f", attenDB);

            swLossCoeff = (1+app.gammaMag^2)/(1 - app.gammaMag^2);
            app.SWLOSSCOEFFLabel.Text = sprintf("S.W. LOSS COEFF = %.2f", swLossCoeff);

            rflLossDB = -10*log10(1 - app.gammaMag^2);
            app.RFLLOSSdBLabel.Text = sprintf("RFL. LOSS [dB] = %.2f", rflLossDB);

            swPeak = sqrt(SWR);
            app.SWPEAKCONSTPLabel.Text = sprintf("S.W. PEAK (CONST. P) = %.2f", swPeak);

            transCoeffP = 1 - app.gammaMag^2;
            app.TRANSMCOEFFPLabel.Text = sprintf("TRANSM. COEFF, P = %.2f", transCoeffP);

            transCoeffE = 1 + app.gammaMag;
            app.TRANSMCOEFFEorILabel.Text = sprintf("TRANSM. COEFF, E or I = %.2f", transCoeffE);

            rflCoeffE = app.gammaMag;
            app.RFLCOEFFEorILabel.Text = sprintf("RFL. COEFF, E or I = %.2f", rflCoeffE);
        end
        
        function updateSWRCircle(app)
            theta = linspace(0, 2*pi, 500);  % Angle values
            r = abs(app.gamma) * app.r1;

            % Outer circle (counterclockwise)
            x = r * cos(theta);
            y = r * sin(theta);

            % Update the plot: 
            set(app.swrCircle, 'XData', x, 'YData', y);
        end

        function userClickResponse(app) 
            % Check if the rotate condition is active:
            if ~app.rotateToggleOn

                % Get the current point of the mouse in the UIAxes
                cp = app.SmithAxes.CurrentPoint;
                x = cp(1,1);
                y = cp(1,2);
    
                % Check if the user selected within the Smith Chart:
                inBounds = isInBounds(app, x, y); % TODO change app to ~?
    
                if inBounds
                    % Update values:
                    app.gammaMag = sqrt(x^2 + y^2)/app.r1;
                    theta = atan2(y,x);
                    app.gammaAngle = rad2deg(theta);
                    updateSmithValues(app, 'gammaAngle')
    
                    % Visual update:
                    updateZCircles(app);
                    updateSWRCircle(app);
                    updateRuler(app);
                    updateRulerMarkings(app);
                    updateRulerValues(app);
                    app.zLPoint.XData = x;
                    app.zLPoint.YData = y;
                end
            else
                % Temporarily add below mouse motion listener (allows real-time graphics updates):
                app.UIFigure.WindowButtonMotionFcn = @(src, event) mouseMoveWhileHeld(app);

                % Update mouse held condition:
                app.mouseHeld = true;
            end
        end


        function endMouseHold(app)
            app.mouseHeld = false;

            % Remove the mouse motion listener:
            app.UIFigure.WindowButtonMotionFcn = [];
        end

        function mouseMoveWhileHeld(app)
            % Check mouse location:
            cp = app.SmithAxes.CurrentPoint;
            x = cp(1,1);
            y = cp(1,2);
            closeEnough = isCloseEnough(app, x, y);

            % Update if: rotate mode is on, the mouse is held down, and is
            % close enough the Smith Chart. 
            if app.rotateToggleOn && app.mouseHeld && closeEnough
                % Update theta:
                theta = atan2(y,x);

                lambdaUpdate(app, theta); % Update lambda

                % Update the Smith Values: TODO update appropraite values,
                % new function? inside lambda update?
                app.gammaAngle = rad2deg(theta);
                updateSmithValues(app, 'gammaAngle') % TODO need a special updatesmith dependent on rotating value (ZL may stay fixed or not)

                % After allowing prior theta and current theta comparison
                % within lambdaUpdate, update app.gammaAngle and other
                % values:

                % Intersection and point update:
                updateZCircles(app);
                updateRuler(app);
            end
        end

        function lambdaUpdate(app, currentTheta) % TODO specify what is currentTheta TODO rename "rotateUpdate"? or "handleRotation?"
            % TODO show start/end wavelength values
            % Check if rotation has begun and initialize process:
            if app.rotatingNow == false
                % The user has begun to actively rotate:
                app.rotatingNow = true;

                app.priorTheta = deg2rad(app.gammaAngle);

                % Compute delta to establish direction (TWG or TWL)
                % app.priorTheta = deg2rad(app.gammaAngle); % Theta before update
                delta = mod(currentTheta - app.priorTheta, 2*pi);

                if delta <= pi
                    app.rotateDirection = "TWL"; % Counterclockwise direction
                    app.rotateDirectionTxt.String = "Toward the LOAD";
                else
                    app.rotateDirection = "TWG"; % Clockwise direction
                    app.rotateDirectionTxt.String = "Toward the GENERATOR";
                end

                % Set the starting point of rotation:
                set(app.initialLine, 'XData', [0 app.r2*cos(app.priorTheta)], 'YData', [0 app.r2*sin(app.priorTheta)], 'Visible', 'on'); % TODO extend r? further out to lambda circle?
                set(app.lambdaCircle, 'Visible', 'on');
                set(app.wavelengthIndicator, 'Visible', 'on');
                
                % Set the starting wavelength tag and point on chart and
                % circuit diagram:
                if app.rotateDirection == "TWL" % zL changes
                    set(app.zinPoint, 'XData', abs(app.gamma)*app.r1*cos(app.priorTheta), 'YData', abs(app.gamma)*app.r1*sin(app.priorTheta)) % Fix zin point
                    set(app.zinCircuitPt1, 'XData', 387, 'YData', 430, 'Visible', 'on')
                    set(app.zinCircuitPt2, 'XData', 387, 'YData', 1845, 'Visible', 'on')
                    wavelengthStart = mod(app.priorTheta - pi, 2*pi)/(4*pi);
                elseif app.rotateDirection == "TWG" % Zin changes
                    set(app.zLPoint, 'XData', abs(app.gamma)*app.r1*cos(app.priorTheta), 'YData', abs(app.gamma)*app.r1*sin(app.priorTheta)) % Fix zL point
                    set(app.zinCircuitPt1, 'XData', 3285, 'YData', 430, 'Visible', 'on')
                    set(app.zinCircuitPt2, 'XData', 3285, 'YData', 1845, 'Visible', 'on')
                    wavelengthStart = mod(-app.priorTheta - pi, 2*pi)/(4*pi);
                end

                % Highlight the appropriate ring:
                fillRotateDirection(app);

                app.wavelengthStartIndicator.Position = [app.r4*cos(app.priorTheta) app.r4*sin(app.priorTheta)];
                app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthStart);

                % Ensure wavelengthStart and End are visible if needed:
                ShowstartendpointsCheckBoxValueChanged(app);
                
                % Set the starting theta value for measurement:
                app.startingTheta = app.priorTheta;

                % Set visiblity on the circuit diagram:
                app.wavelengthIndicatorCircuit.Visible = 'on';

            else % Runs while rotating:
                % Unwrap currentTheta in relation to app.priorTheta (prevents
                % discontinuites at theta = +-pi and allows multiple
                % traces.
                disp("prior theta: ")
                disp(app.priorTheta)
                disp("current theta: ")
                disp(currentTheta)
                unwrappedThetaValues = unwrap([app.priorTheta currentTheta]);
                currentTheta = unwrappedThetaValues(2);

                disp("unwrapped: ")
                disp(unwrappedThetaValues)

                % Store currentTheta into app.priorTheta for next use
                % app.priorTheta = currentTheta;
                % Store currentTheta into app.priorTheta for next use
                app.priorTheta = currentTheta;

                if app.rotateDirection == "TWL"
                    delta = currentTheta - app.startingTheta;
                    app.zLPoint.XData = app.gammaMag*app.r1*cos(currentTheta);
                    app.zLPoint.YData = app.gammaMag*app.r1*sin(currentTheta);
                elseif app.rotateDirection == "TWG"
                    delta = app.startingTheta - currentTheta;
                    app.zinPoint.XData = app.gammaMag*app.r1*cos(currentTheta);
                    app.zinPoint.YData = app.gammaMag*app.r1*sin(currentTheta);
                end

                % Update wavelength indicator:
                conversionRatio = 0.5/(2*pi);
                wavelengths = conversionRatio*delta;

                updateWavelengthIndicator(app, mod(currentTheta, 2*pi), wavelengths)

                % Determine number of points in lambdaCircle:
                n = abs(round((app.circleQuality/(2*pi))*delta));
                thetaValues = linspace(app.startingTheta, currentTheta, n);

                % Update the circle:
                xData = app.r4 * cos(thetaValues);
                yData = app.r4 * sin(thetaValues);
                set(app.lambdaCircle, 'XData', xData, 'YData', yData)

                % Update the circuit diagram:
                circuitDiagramUpdate(app, wavelengths)
            end
        end

        % TODO create function headers for each of these
        function updateWavelengthIndicator(app, theta, wavelengths)
            % Update the text:
            app.wavelengthIndicator.String = sprintf("ℓ = %.3fλ", wavelengths);
            app.wavelengthIndicator.Position = [app.r4*cos(theta) app.r4*sin(theta)];

            % Set the starting wavelength tag:
            if app.rotateDirection == "TWL"
                wavelengthEnd = mod(theta - pi, 2*pi)/(4*pi);
            elseif app.rotateDirection == "TWG"
                wavelengthEnd = mod(-theta - pi, 2*pi)/(4*pi);
            end

            app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
            app.wavelengthEndIndicator.Position = [app.r4*cos(theta) app.r4*sin(theta) + 100]; % Always just above the wavelengthIndicator
        end

        function circuitDiagramUpdate(app, wavelengths) % TODO NOW  
            if app.rotateDirection == "TWG" % Zin changes:
                % Update ZL: TODO bug: zl here and zl in the text boxes are
                % TODO SHOULD BE  Zin. % TODO update normalized zl on diagram
                % misaligned. 
                app.ZinTextCircuit.String = sprintf("z_{in}=%.2f+%.2fj", real(app.zL), imag(app.zL)); % TODO

                % Update wavelength:
                conversionRatio = 2837/(0.5); % Ratio of the length of the transmission line on the circuit diagram and max (expected) wavelengths to show.
                endingPoint = 3200 - conversionRatio*wavelengths;
                set(app.wavelengthIndicatorCircuit, 'XData', [endingPoint 3200], 'YData', [300 300])

                % Update zin indicator:
                set(app.zinCircuitPt1, 'XData', endingPoint, 'YData', 430, 'Visible', 'on')
                set(app.zinCircuitPt2, 'XData', endingPoint, 'YData', 1845, 'Visible', 'on')
            elseif app.rotateDirection == "TWL" % ZL changes:
                app.ZLTextCircuit.String = sprintf("z_{L} =%.2f+%.2fj", real(app.zL), imag(app.zL));

                % Update wavelength:
                conversionRatio = 2837/(0.5); % Ratio of the length of the transmission line on the circuit diagram and max (expected) wavelengths to show.
                endingPoint = 385 + wavelengths*conversionRatio;
                set(app.wavelengthIndicatorCircuit, 'XData', [385 endingPoint], 'YData', [300 300])
            end

            app.wavelengthTextCircuit.String = sprintf("ℓ=%.3fλ", wavelengths);
            app.wavelengthTextCircuit.Position = [endingPoint 100];


        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load image:
            loadImages(app);

            % Disable data tips:
            datacursormode(app.UIFigure, 'off');

            % Color the angle of reflection coefficient:
            fillGammaAngle(app);

            % Set listener for selecting points on the Smith Chart
            app.mouseListener = event.listener(app.UIFigure,'WindowMousePress', @(src,evt) userClickResponse(app));

            % Set listener for rotating:
            % Initialize control flags
            app.mouseHeld = false;  % Tracks whether the mouse is being held

            % Add additional listener to help with rotating:
            app.mouseReleaseListener = event.listener(app.UIFigure, 'WindowMouseRelease', @(src,evt) endMouseHold(app));

            % Set listener for resizing the app window:
            app.MainPanel.AutoResizeChildren = 'off';
            app.MainPanel.SizeChangedFcn = @(~, ~) onResize(app);

            % Initialize ruler plots:
            app.innerRuler = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Visible', 'off', 'Color', app.okabeBluishGreen);
            app.innerRuler.PickableParts = 'none';
            app.outerRuler = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeSkyBlue, 'Visible', 'off'); % alpha = .4
            app.outerRuler.PickableParts = 'none';

            % Initialize ruler marking handles:
            for i = 1:7
                app.rulerMarkings(i) = plot(app.RulerAxes, nan, nan, 'LineWidth', 3, 'Visible', 'off', 'Color', app.okabeBluishGreen);
                app.rulerMarkings(i).PickableParts = 'none';
            end

            % Doing a separate loop ensures these points are on top in the
            % UI stack:
            for i = 1:7
                app.rulerPoints(i) = scatter(app.RulerAxes, nan, nan, 50, 'filled', 'Visible', 'off');
                app.rulerPoints(i).CData = app.okabeOrange;
                app.rulerPoints(i).PickableParts = 'none';
            end

            % Initialize the intersecting line plots:
            app.imgIntersection = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeBlue); % Blue line
            app.imgIntersection.PickableParts = 'none';
            app.realIntersection = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeReddishPurple); % Reddish purple line
            app.realIntersection.PickableParts = 'none';

            % Initialize SWR circle plot:
            app.swrCircle = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeVermilion, 'Visible', 'off');
            app.swrCircle.PickableParts = 'none';

            % Change ZL and zL text fields' color: 
            app.ZLEditField.FontColor = app.okabeOrange;
            app.zLEditField.FontColor = app.okabeOrange;

            % Set up lambda measurements for TWG and TWL:
            app.rotateDirectionTxt = text(app.SmithAxes, 1500, 1800, ".", 'FontSize', 16, 'FontWeight', 'bold');
            app.rotateDirectionTxt.Visible = 'off';

            % Initialize starting line for rotating:
            app.initialLine = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', 'k', 'Visible', 'off');
            
            % TODO add to wavelength thing:
            app.wavelengthIndicator = text(app.SmithAxes, 0, 0, 'ℓ = ', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 16, 'FontWeight','bold', 'Visible', 'off');
            app.lambdaCircle = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeLightVermilion, 'Visible', 'off', 'PickableParts', 'none');
            app.wavelengthStartIndicator = text(app.SmithAxes, 0, 0, 'ℓ start = ', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 12, 'FontWeight','bold', 'Visible', 'off');
            app.wavelengthEndIndicator = text(app.SmithAxes, 0, 0, 'ℓ end = ', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 12, 'FontWeight','bold', 'Visible', 'off');

            % Initialize the rotateRing patch object:
            app.rotateRing = fill(app.SmithAxes, nan, nan, app.okabeYellow); % TODO color okay?
            app.rotateRing.FaceAlpha = 0.3;
            app.rotateRing.EdgeColor = 'none';
            app.rotateRing.PickableParts = 'none';

            % Initialize zin markers for the circuit diagram:
            app.zinCircuitPt1 = scatter(app.CircuitAxes, nan, nan, 75, 'filled', 'Marker', '>', 'Visible', 'off', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', app.okabeBlue);
            app.zinCircuitPt2 = scatter(app.CircuitAxes, nan, nan, 75, 'filled', 'Marker', '>', 'Visible', 'off', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', app.okabeBlue);

            % Initialize app.zinPoint:
            app.zinPoint = scatter(app.SmithAxes, nan, nan, 100, 'filled', 'Marker', '>', 'Visible', 'off', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', app.okabeBlue);
            app.zinPoint.PickableParts = 'none';

            % Initialize app.zLPoint:
            app.zLPoint = scatter(app.SmithAxes, nan, nan, 100, 'filled');
            app.zLPoint.CData = app.okabeOrange;
            app.zLPoint.PickableParts = 'none';

            % TODO tool-bar customizaton: IDEA: Timer. If the user
            % clicks BRIEFLY, it will select a spot. if they hold, it will
            % assume the user is dragging? (think iPhone usage cases)

            % Set up the circuit diagram:
            app.ZLTextCircuit = text(app.CircuitAxes, 3175, 1094, 'z_{L} = ???', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Visible', 'on');
            app.ZinTextCircuit = text(app.CircuitAxes, 393, 1094, 'z_{in}=???', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Visible', 'on');
            app.wavelengthTextCircuit = text(app.CircuitAxes, 3200, 300, 'ℓ=???', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Visible', 'on');
            app.wavelengthIndicatorCircuit = plot(app.CircuitAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeLightVermilion, 'Visible', 'off');

            % TODO Adjust subscripts in the text?: (use z_{L} for
            % subscripts.)
            % app.ZLEditField.Label = "asdfasdf";
        end

        % Value changed function: ZLEditField
        function ZLEditFieldValueChanged(app, event)
            str = app.ZLEditField.Value; % e.g., '3+4i'
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.ZL = val;  % Store the complex number
            % Update other variables:
            updateSmithValues(app, 'ZL');
            updateSmithPoint(app);

        end

        % Value changed function: gammaMagEditField
        function gammaMagEditFieldValueChanged(app, event)
            app.gammaMag = app.gammaMagEditField.Value;
            updateSmithValues(app, 'gammaMag');
            updateSmithPoint(app);
            
        end

        % Value changed function: gammaAngleEditField
        function gammaAngleEditFieldValueChanged(app, event)
            app.gammaAngle = app.gammaAngleEditField.Value;
            updateSmithValues(app, 'gammaAngle');
            updateSmithPoint(app);
            
        end

        % Value changed function: zLEditField
        function zLEditFieldValueChanged(app, event)
            str = app.zLEditField.Value;
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.zL = val;  % Store the complex number
            % Update other variables:
            updateSmithValues(app, 'zL');
            updateSmithPoint(app)
        end

        % Value changed function: yLEditField
        function yLEditFieldValueChanged(app, event)
            str = app.yLEditField.Value;
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.yL = val;  % Store the complex number
            % Update other variables:
            updateSmithValues(app, 'yL');
            updateSmithPoint(app)
            
        end

        % Value changed function: YLEditField
        function YLEditFieldValueChanged(app, event)
            str = app.YLEditField.Value;
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.YL = val;  % Store the complex number
            % Update other variables:
            updateSmithValues(app, 'YL');
            updateSmithPoint(app)
            
        end

        % Value changed function: RulerCheckBox
        function RulerCheckBoxValueChanged(app, event)
            value = app.RulerCheckBox.Value;
            
            if value
                app.innerRuler.Visible = 'on';
                app.outerRuler.Visible = 'on';

                for i = 1:7
                    app.rulerMarkings(i).Visible = 'on';
                    app.rulerPoints(i).Visible = 'on';
                end
            else
                app.innerRuler.Visible = 'off';
                app.outerRuler.Visible = 'off';

                for i = 1:7
                    app.rulerMarkings(i).Visible = 'off';
                    app.rulerPoints(i).Visible = 'off';
                end
            end

        end

        % Value changed function: SWRCircleCheckBox
        function SWRCircleCheckBoxValueChanged(app, event)
            value = app.SWRCircleCheckBox.Value;

            if value
                app.swrCircle.Visible = 'on';
            else
                app.swrCircle.Visible = 'off';
            end
            
        end

        % Value changed function: RotateToggleButton
        function RotateToggleButtonValueChanged(app, event)
            value = app.RotateToggleButton.Value;
            % TODO NOTE: Needs to update gamma as rotating... is it doing
            % that? something is going on

            if value
                app.rotateToggleOn = true;
                app.RotateToggleButton.Text = "Rotate: ON";
                app.rotateDirectionTxt.Visible = 'on';

                app.rotateDirectionTxt.String = "Toward ...";
                app.ResetRotationButton.Enable = 'on';

                app.zinPoint.Visible = 'on';

                % TODO temporary fix: Disable panning and zooming:
                app.SmithAxes.InteractionOptions.PanSupported = 'off';

                % Enable ruler and SWR circle:
                app.SWRCircleCheckBox.Value = true;
                app.RulerCheckBox.Value = true;
                SWRCircleCheckBoxValueChanged(app);
                RulerCheckBoxValueChanged(app);

                % Turn off Z-circles:
                app.ZCirclesCheckBox.Value = false;
                ZCirclesCheckBoxValueChanged(app);
            else
                app.rotateToggleOn = false;
                app.RotateToggleButton.Text = "Rotate: OFF";
                app.rotateDirectionTxt.Visible = 'off';
                app.ResetRotationButton.Enable = 'off';
                app.rotatingNow = false;

                % TODO temporary fix: Enable panning and zooming:
                app.SmithAxes.InteractionOptions.PanSupported = 'on';

                app.wavelengthIndicatorCircuit.Visible = 'off';

                app.wavelengthStartIndicator.Visible = 'off';
                app.wavelengthEndIndicator.Visible = 'off';
                app.wavelengthIndicator.Visible = 'off';

                app.lambdaCircle.Visible = 'off';
                app.initialLine.Visible = 'off';
                % app.rotateRing.Visible = 'off'; % TODO
            end

        end

        % Button pushed function: ResetRotationButton
        function ResetRotationButtonPushed(app, event)
            app.rotatingNow = false;
            app.rotateDirectionTxt.String = "Toward ...";
            app.initialLine.Visible = 'off';
            app.wavelengthIndicator.Visible = 'off';
            app.lambdaCircle.Visible = 'off';
            app.wavelengthIndicatorCircuit.Visible = 'off';
            app.wavelengthStartIndicator.Visible = 'off';
            app.wavelengthEndIndicator.Visible = 'off';
            

            % TODO reset the other lines as well (start line and end line)
        end

        % Value changed function: ZCirclesCheckBox
        function ZCirclesCheckBoxValueChanged(app, event)
            value = app.ZCirclesCheckBox.Value;
            
            if value
                app.realIntersection.Visible = 'on';
                app.imgIntersection.Visible = 'on';
            else
                app.realIntersection.Visible = 'off';
                app.imgIntersection.Visible = 'off';
            end
        end

        % Value changed function: ShowstartendpointsCheckBox
        function ShowstartendpointsCheckBoxValueChanged(app, event)
            value = app.ShowstartendpointsCheckBox.Value;
            
            if value
                app.wavelengthStartIndicator.Visible = 'on';
                app.wavelengthEndIndicator.Visible = 'on';
            else
                app.wavelengthStartIndicator.Visible = 'off';
                app.wavelengthEndIndicator.Visible = 'off';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CircuitAxes = uiaxes(app.GridLayout);
            title(app.CircuitAxes, 'Circuit Diagram')
            xlabel(app.CircuitAxes, 'X')
            ylabel(app.CircuitAxes, 'Y')
            zlabel(app.CircuitAxes, 'Z')
            app.RulerCheckBox = uicheckbox(app.GridLayout);
            app.RulerCheckBox.ValueChangedFcn = createCallbackFcn(app, @RulerCheckBoxValueChanged, true);
            app.RulerCheckBox.Text = 'Ruler';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '|Γ|';
            app.gammaMagEditField = uieditfield(app.GridLayout, 'numeric');
            app.gammaMagEditField.Limits = [0 1];
            app.gammaMagEditField.ValueChangedFcn = createCallbackFcn(app, @gammaMagEditFieldValueChanged, true);
            app.ZLLabel = uilabel(app.GridLayout);
            app.ZLLabel.Text = 'ZL (Ω) = ';
            app.ZLEditField = uieditfield(app.GridLayout, 'text');
            app.ZLEditField.ValueChangedFcn = createCallbackFcn(app, @ZLEditFieldValueChanged, true);
            app.zLEditFieldLabel = uilabel(app.GridLayout);
            app.zLEditFieldLabel.Text = 'zL';
            app.zLEditField = uieditfield(app.GridLayout, 'text');
            app.zLEditField.ValueChangedFcn = createCallbackFcn(app, @zLEditFieldValueChanged, true);
            app.YLLabel = uilabel(app.GridLayout);
            app.YLLabel.Text = 'YL (℧)';
            app.YLEditField = uieditfield(app.GridLayout, 'text');
            app.YLEditField.ValueChangedFcn = createCallbackFcn(app, @YLEditFieldValueChanged, true);
            app.degEditFieldLabel = uilabel(app.GridLayout);
            app.degEditFieldLabel.Text = '∠Γ (deg)';
            app.gammaAngleEditField = uieditfield(app.GridLayout, 'numeric');
            app.gammaAngleEditField.ValueChangedFcn = createCallbackFcn(app, @gammaAngleEditFieldValueChanged, true);
            app.yLEditFieldLabel = uilabel(app.GridLayout);
            app.yLEditFieldLabel.Text = 'yL';
            app.yLEditField = uieditfield(app.GridLayout, 'text');
            app.yLEditField.ValueChangedFcn = createCallbackFcn(app, @yLEditFieldValueChanged, true);
            app.RLLabel = uilabel(app.GridLayout);
            app.RLLabel.Text = 'RL';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '+';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '=';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '=';
            app.XLjLabel = uilabel(app.GridLayout);
            app.XLjLabel.Text = 'XLj';
            app.MainPanel = uipanel(app.GridLayout);
            app.MainPanel.Title = 'Panel';
            app.SmithAxes = uiaxes(app.MainPanel);
            title(app.SmithAxes, 'Smith Chart')
            xlabel(app.SmithAxes, 'X')
            ylabel(app.SmithAxes, 'Y')
            zlabel(app.SmithAxes, 'Z')
            app.RulerAxes = uiaxes(app.MainPanel);
            title(app.RulerAxes, 'Ruler')
            xlabel(app.RulerAxes, 'X')
            ylabel(app.RulerAxes, 'Y')
            zlabel(app.RulerAxes, 'Z')
            app.SWRLabel = uilabel(app.MainPanel);
            app.SWRLabel.Text = 'SWR = ';
            app.dBSLabel = uilabel(app.MainPanel);
            app.dBSLabel.Text = 'dBS = ';
            app.RTNLOSSdBLabel = uilabel(app.MainPanel);
            app.RTNLOSSdBLabel.Text = 'RTN. LOSS [dB] = ';
            app.RFLCOEFFPLabel = uilabel(app.MainPanel);
            app.RFLCOEFFPLabel.Text = 'RFL. COEFF, P = ';
            app.ATTENdbLabel = uilabel(app.MainPanel);
            app.ATTENdbLabel.Text = 'ATTEN. [db] = ';
            app.SWLOSSCOEFFLabel = uilabel(app.MainPanel);
            app.SWLOSSCOEFFLabel.Text = 'S.W. LOSS COEFF = ';
            app.RFLLOSSdBLabel = uilabel(app.MainPanel);
            app.RFLLOSSdBLabel.Text = 'RFL. LOSS [dB] = ';
            app.SWPEAKCONSTPLabel = uilabel(app.MainPanel);
            app.SWPEAKCONSTPLabel.Text = 'S.W. PEAK (CONST. P) = ';
            app.TRANSMCOEFFPLabel = uilabel(app.MainPanel);
            app.TRANSMCOEFFPLabel.Text = 'TRANSM. COEFF, P = ';
            app.TRANSMCOEFFEorILabel = uilabel(app.MainPanel);
            app.TRANSMCOEFFEorILabel.Text = 'TRANSM. COEFF, E or I = ';
            app.RFLCOEFFEorILabel = uilabel(app.MainPanel);
            app.RFLCOEFFEorILabel.Text = 'RFL. COEFF, E or I = ';
            app.ZLLabel_2 = uilabel(app.GridLayout);
            app.ZLLabel_2.Text = '1/ZL = ';
            app.ZL50Label = uilabel(app.GridLayout);
            app.ZL50Label.Text = 'ZL/50(Ω) = ';
            app.YL50Label = uilabel(app.GridLayout);
            app.YL50Label.Text = 'YL*50(Ω) = ';
            app.SWRCircleCheckBox = uicheckbox(app.GridLayout);
            app.SWRCircleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SWRCircleCheckBoxValueChanged, true);
            app.SWRCircleCheckBox.Text = 'SWR Circle';
            app.RotateToggleButton = uibutton(app.GridLayout, 'state');
            app.RotateToggleButton.ValueChangedFcn = createCallbackFcn(app, @RotateToggleButtonValueChanged, true);
            app.RotateToggleButton.Text = 'Rotate OFF';
            app.ResetRotationButton = uibutton(app.GridLayout, 'push');
            app.ResetRotationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRotationButtonPushed, true);
            app.ResetRotationButton.Enable = 'off';
            app.ResetRotationButton.Text = 'Reset Rotation';
            app.ZCirclesCheckBox = uicheckbox(app.GridLayout);
            app.ZCirclesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZCirclesCheckBoxValueChanged, true);
            app.ZCirclesCheckBox.Text = 'Z Circles';
            app.ZCirclesCheckBox.Value = true;
            app.ShowstartendpointsCheckBox = uicheckbox(app.GridLayout);
            app.ShowstartendpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowstartendpointsCheckBoxValueChanged, true);
            app.ShowstartendpointsCheckBox.Text = 'Show start/end points';
            app.ZinEditFieldLabel = uilabel(app.GridLayout);
            app.ZinEditFieldLabel.Text = 'Zin';
            app.ZinEditField = uieditfield(app.GridLayout, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.GridLayout);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmithChartIntermediate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end