classdef SnellAndBoundarySIm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SnellsLawTab                  matlab.ui.container.Tab
        TransmittedLabel              matlab.ui.control.Label
        ReflectedLabel                matlab.ui.control.Label
        IncidentLabel                 matlab.ui.control.Label
        Medium2r2Label                matlab.ui.control.Label
        Medium1r1Label                matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        TotalInternalReflectionLabel  matlab.ui.control.Label
        TransmittedangleLabel         matlab.ui.control.Label
        ReflectedangleLabel           matlab.ui.control.Label
        OUTPUTSLabel                  matlab.ui.control.Label
        INPUTSLabel                   matlab.ui.control.Label
        Epsilonr2EditField            matlab.ui.control.NumericEditField
        r2EditFieldLabel              matlab.ui.control.Label
        Epsilonr1EditField            matlab.ui.control.NumericEditField
        r1EditFieldLabel              matlab.ui.control.Label
        thetaiSlider                  matlab.ui.control.Slider
        iSliderLabel                  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        Tab2                          matlab.ui.container.Tab
    end


    properties (Access = private)
        er1 = 0;
        er2 = 0;
        theta_i;
        theta_r;
        theta_t;
        n1;
        n2;
        L = 5;

        iRay;
        rRay;
        tRay;

        okabeBlue = [0.0000 0.4471 0.6980];
        okabeReddishPurple = [204, 121, 167]/255;
        okabeBluishGreen = [0.0000, 0.6196, 0.4510];


    end

    methods (Access = private)

        function updateValues(app)

            if app.er1 <= 0 || app.er2 <= 0
                return
            end

            app.n1 = sqrt(app.er1);
            app.n2 = sqrt(app.er2);
            ratio = (app.n1/app.n2) * sind(app.theta_i);

            if abs(ratio) > 1
                app.theta_t = NaN;   % total internal reflection TIR = YES
                app.TransmittedangleLabel.Text = 'Transmitted Angle (θₜ) = N/A';
                app.TotalInternalReflectionLabel.Text = "Total Internal Reflection = YES";
            else
                app.theta_t = asind(ratio);
                app.TransmittedangleLabel.Text = sprintf('Transmitted Angle (θₜ) = %.2f°', app.theta_t);

                app.TotalInternalReflectionLabel.Text = "Total Internal Reflection = NO";


            end

            app.ReflectedangleLabel.Text = sprintf('Reflected Angle (θᵣ) = %.2f°', app.theta_r);
            app.theta_r = app.theta_i;
            createRays(app);


        end

        function createRays(app)

            % % Recreate quivers if needed
            % if isempty(app.iRay) || ~isvalid(app.iRay)
            %     app.iRay = quiver(app.UIAxes,0,0,1,1,0,'Color',app.okabeBlue,'LineWidth',2);
            % end
            % if isempty(app.rRay) || ~isvalid(app.rRay)
            %     app.rRay = quiver(app.UIAxes,0,0,1,1,0,'Color',app.okabeReddishPurple,'LineWidth',2);
            % end
            % if isempty(app.tRay) || ~isvalid(app.tRay)
            %     app.tRay = quiver(app.UIAxes,0,0,1,1,0,'Color',app.okabeBluishGreen,'LineWidth',2);
            % end


            x_i = -app.L*sind(app.theta_i);
            y_i = app.L*cosd(app.theta_i);

            u_i = -x_i;
            v_i = -y_i;

            set(app.iRay,...
                'XData',x_i,...
                'YData',y_i,...
                'UData',u_i,...
                'VData',v_i);

            % disp(app.iRay)
            % 
            % if isempty(app.iRay)
            %     disp('EMPTY')
            % else
            %     disp(isvalid(app.iRay))
            % end

            % app.iRay.XData = x_i;
            % app.iRay.YData = y_i;
            % app.iRay.UData = u_i;
            % app.iRay.VData = v_i;


            u_r = app.L *sind(app.theta_r);
            v_r = app.L*cosd(app.theta_r);

            set(app.rRay,...
                'XData',0,...
                'YData',0,...
                'UData',u_r,...
                'VData',v_r);

            if isnan(app.theta_t)
                set(app.tRay,'Visible','off');
            else
                set(app.tRay,'Visible','on');
                u_t = app.L *sind(app.theta_t);
                v_t = -app.L*cosd(app.theta_t);

                set(app.tRay,...
                    'XData',0,...
                    'YData',0,...
                    'UData',u_t,...
                    'VData',v_t);

            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            hold(app.UIAxes, "on");
            plot(app.UIAxes, [-10 10], [0 0], 'k', 'LineWidth', 2);
            plot(app.UIAxes, [0 0], [-10 10], '--');
            app.iRay = quiver(app.UIAxes,0,0,1,1, 0,'Color', app.okabeBlue, 'LineWidth',2);
            app.rRay = quiver(app.UIAxes,0,0,1,1, 0,'Color', app.okabeReddishPurple, 'LineWidth',2);
            app.tRay = quiver(app.UIAxes,0,0,1,1, 0, 'Color', app.okabeBluishGreen, 'LineWidth',2);

            hold(app.UIAxes, "off");

        end

        % Value changed function: Epsilonr1EditField
        function Epsilonr1EditFieldValueChanged(app, event)
            app.er1 = app.Epsilonr1EditField.Value;
            updateValues(app);


        end

        % Value changed function: Epsilonr2EditField
        function Epsilonr2EditFieldValueChanged(app, event)
            app.er2 = app.Epsilonr2EditField.Value;
            updateValues(app);


        end

        % Value changed function: thetaiSlider
        function thetaiSliderValueChanged(app, event)
            app.theta_i = app.thetaiSlider.Value;
            updateValues(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SnellsLawTab = uitab(app.TabGroup);
            app.SnellsLawTab.Title = 'Snell''s Law';
            app.UIAxes = uiaxes(app.SnellsLawTab);
            title(app.UIAxes, 'Snell''s Law Visualization')
            xlabel(app.UIAxes, 'Horizontal Distance')
            ylabel(app.UIAxes, 'Vertical Distance')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.SnellsLawTab);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.iSliderLabel = uilabel(app.GridLayout);
            app.iSliderLabel.Text = 'θi';
            app.thetaiSlider = uislider(app.GridLayout);
            app.thetaiSlider.Limits = [0 89];
            app.thetaiSlider.ValueChangedFcn = createCallbackFcn(app, @thetaiSliderValueChanged, true);
            app.r1EditFieldLabel = uilabel(app.GridLayout);
            app.r1EditFieldLabel.Text = 'εr1';
            app.Epsilonr1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Epsilonr1EditField.ValueChangedFcn = createCallbackFcn(app, @Epsilonr1EditFieldValueChanged, true);
            app.r2EditFieldLabel = uilabel(app.GridLayout);
            app.r2EditFieldLabel.Text = 'εr2';
            app.Epsilonr2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Epsilonr2EditField.ValueChangedFcn = createCallbackFcn(app, @Epsilonr2EditFieldValueChanged, true);
            app.INPUTSLabel = uilabel(app.GridLayout);
            app.INPUTSLabel.Text = 'INPUTS:';
            app.OUTPUTSLabel = uilabel(app.GridLayout);
            app.OUTPUTSLabel.Text = 'OUTPUTS:';
            app.ReflectedangleLabel = uilabel(app.GridLayout);
            app.ReflectedangleLabel.Text = 'Reflected angle (θᵣ) = ????';
            app.TransmittedangleLabel = uilabel(app.GridLayout);
            app.TransmittedangleLabel.Text = 'Transmitted angle (θₜ) = ????';
            app.TotalInternalReflectionLabel = uilabel(app.GridLayout);
            app.TotalInternalReflectionLabel.Text = 'Total Internal Reflection = ????';
            app.Medium1r1Label = uilabel(app.SnellsLawTab);
            app.Medium1r1Label.Text = 'Medium 1 (εr1)';
            app.Medium2r2Label = uilabel(app.SnellsLawTab);
            app.Medium2r2Label.Text = 'Medium 2 (εr2)';
            app.IncidentLabel = uilabel(app.SnellsLawTab);
            app.IncidentLabel.Text = 'Incident';
            app.ReflectedLabel = uilabel(app.SnellsLawTab);
            app.ReflectedLabel.Text = 'Reflected';
            app.TransmittedLabel = uilabel(app.SnellsLawTab);
            app.TransmittedLabel.Text = 'Transmitted';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SnellAndBoundarySIm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end